classdef FinalApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        PauseButton             matlab.ui.control.StateButton
        LoadSettingsButton      matlab.ui.control.Button
        SaveSettingsButton      matlab.ui.control.Button
        SensorTable             matlab.ui.control.Table
        SampleRateSpinner       matlab.ui.control.Spinner
        SampleRateSpinnerLabel  matlab.ui.control.Label
        NumberofSamplesSpinner  matlab.ui.control.Spinner
        NumberofSamplesLabel    matlab.ui.control.Label
        HarryRogersAppLabel     matlab.ui.control.Label
        ConnectButton           matlab.ui.control.StateButton
        NotConnectedLamp        matlab.ui.control.Lamp
        NotConnectedLampLabel   matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
% These represent the background variables required for the app to function
    properties (Access = private)
        ArduinoConnection  % The link to the actual Arduino board
        PollingTimer       % The background clock that triggers the data reading
        ArduinoVoltages    % The grid where the raw voltage numbers are saved
        NumSamples         % The number of samples kept on the screen
        PinLines           % The drawn lines on the graph
        PinColors          % The colours for the lines
        AllPins            % A list of the available pins
    end
    
    methods (Access = private)
% This is the main process that reads data and updates the picture
        function DoPolling(app, ~, ~)
            % Stop execution if the Arduino is not connected
            if isempty(app.ArduinoConnection)
                return;
            end
            
            allPins = app.AllPins;
            
            % Read the data from the UI table
            sensorData = app.SensorTable.Data;
            
            % Keep a list of custom names for the legend
            activeLegendNames = {};
        
            % ===== RECORD THE DATA =====
            % Space must be made for new data. This is achieved by removing 
            % the oldest row at the top and shifting all other rows up by one.
            app.ArduinoVoltages(1:end-1,:) = app.ArduinoVoltages(2:end,:);
        
            % Read the incoming voltages from the Arduino into the bottom row
            for k = 1:numel(allPins)
                app.ArduinoVoltages(end,k) = ...
                    readVoltage(app.ArduinoConnection, allPins{k});
            end
        
            % ===== UPDATE THE GRAPH WITH SCALING =====
            for k = 1:numel(allPins)
                % Extract properties for this specific pin from the table
                sensorName = sensorData.Name{k};
                
                % Grab the checkbox state (true or false)
                isVisible = sensorData.Enable(k); 
                
                % EFFICIENT LOGIC: Only do the maths and update the line if it is enabled
                if isVisible
                    scale = sensorData.Scale(k);
                    offset = sensorData.Offset(k);
                    
                    % Apply the linear scaling maths: (Raw * Scale) + Offset
                    scaledData = (app.ArduinoVoltages(:, k) * scale) + offset;
                    
                    % Assign the scaled data to the existing line
                    app.PinLines(k).YData = scaledData;
                    app.PinLines(k).XData = 1:app.NumSamples;
                    
                    app.PinLines(k).Visible = 'on';
                    
                    % Add the custom name to the legend list only if visible
                    activeLegendNames{end+1} = sensorName; 
                else
                    % If the pin is not selected, simply hide it and skip the calculations
                    app.PinLines(k).Visible = 'off';
                end
            end
            
            % Update the legend with the custom names from the table
            if ~isempty(activeLegendNames)
                legend(app.UIAxes, activeLegendNames, 'Location', 'northwest');
            end
            
            % Update the Y-axis label to match the first sensor's unit
            % (Assuming all displayed sensors share a similar unit for plotting)
            if height(sensorData) > 0
                ylabel(app.UIAxes, sensorData.Unit{1});
            end
        
            % Limit the refresh rate to prevent the application from crashing
            drawnow limitrate
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           % A background timer allows buttons to be clicked while the 
            % application reads data.
            app.PollingTimer = timer("ExecutionMode", "fixedRate", ...
                                     "Period", app.SampleRateSpinner.Value, ...
                                     "TimerFcn", @app.DoPolling);
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            buttonState = app.ConnectButton.Value;
            
            if buttonState % Connect was clicked
                try
                    % Attempt a connection to the board
                    app.ArduinoConnection = arduino;
            
                    % Retrieve the available pins
                    pins = app.ArduinoConnection.AvailableAnalogPins';
                    numPins = numel(pins);
                    
                    % ===== SETUP THE SENSOR TABLE =====
                    % Create default values for the table columns
                    defaultNames = repmat({'New Sensor'}, numPins, 1);
                    defaultUnits = repmat({'Volts'}, numPins, 1);
                    defaultScales = ones(numPins, 1); 
                    defaultOffsets = zeros(numPins, 1); 
                    defaultEnable = true(numPins, 1); % Logical true for checkboxes
                    
                    % Combine into a table and display it in the UI
                    app.SensorTable.Data = table(pins, defaultNames, defaultUnits, defaultScales, defaultOffsets, defaultEnable, ...
                        'VariableNames', {'Pin', 'Name', 'Unit', 'Scale', 'Offset', 'Enable'});
            
                    % Change the indicator light to green and update the text
                    app.NotConnectedLamp.Color = [0 1 0];
                    app.NotConnectedLampLabel.Text = 'Connected';
                    app.ConnectButton.Text = 'Disconnect'; 
            
                    % Fill the initial data grid with zeros. Preallocating 
                    % this space prevents the application from running slowly.
                    app.NumSamples = app.NumberofSamplesSpinner.Value;
                    app.ArduinoVoltages = zeros(app.NumSamples, numPins);
            
                    app.AllPins = pins;
                    app.PinColors = lines(numPins);
                    
                    % Create all the lines at startup but keep them hidden. 
                    % Generating them in advance improves execution speed.
                    hold(app.UIAxes, 'on')
                    app.PinLines = gobjects(1, numPins);
                    
                    for k = 1:numPins
                        app.PinLines(k) = plot( ...
                            app.UIAxes, ...
                            nan(app.NumSamples,1), ...
                            'LineWidth', 1.5, ...
                            'Color', app.PinColors(k,:), ...
                            'Visible', 'off');
                    end
                    
                    hold(app.UIAxes, 'off')
                    
                    % Begin reading the data
                    start(app.PollingTimer);
                    
                catch ME
                    % If the connection fails, reset the button and display 
                    % an alert box instead of failing silently.
                    app.ConnectButton.Value = 0; 
                    uialert(app.UIFigure, "Failed to connect: " + ME.message, "Arduino Error");
                end
            else % Disconnect was clicked
                % Stop the timer and close the connection
                stop(app.PollingTimer);
                clear app.ArduinoConnection;
                
                % Revert the indicator light to red
                app.NotConnectedLamp.Color = [1 0 0];
                app.NotConnectedLampLabel.Text = 'Not Connected';
                app.ConnectButton.Text = 'Connect';
            end
                
        end

        % Callback function
        function AvailablePinsListBoxClicked(app, event)

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % 1. Stop and delete the timer if it exists
            if isvalid(app.PollingTimer)
                stop(app.PollingTimer);
                delete(app.PollingTimer);
            end
            
            % 2. Clear the Arduino connection
            if ~isempty(app.ArduinoConnection)
                clear app.ArduinoConnection;
            end
            
            % 3. Finally, delete the app figure to actually close the window
            delete(app);
            
        end

        % Value changed function: SampleRateSpinner
        function SampleRateSpinnerValueChanged(app, event)
            newRate = app.SampleRateSpinner.Value;
            
            % To update the speed safely, the timer is paused, the 
            % value is updated, and the timer is restarted.
            if ~isempty(app.PollingTimer) && isvalid(app.PollingTimer)
                wasRunning = strcmp(app.PollingTimer.Running, 'on');
                
                if wasRunning
                    stop(app.PollingTimer);
                end
                
                app.PollingTimer.Period = newRate;
                
                if wasRunning
                    start(app.PollingTimer);
                end
            end
        end

        % Value changed function: NumberofSamplesSpinner
        function NumberofSamplesSpinnerValueChanged(app, event)
            newSamples = app.NumberofSamplesSpinner.Value;
            oldSamples = app.NumSamples;
            app.NumSamples = newSamples;
            
            % If the sample count changes, the data grid must be 
            % resized to prevent graph errors.
            if ~isempty(app.ArduinoVoltages)
                numCols = size(app.ArduinoVoltages, 2);
                
                if newSamples > oldSamples
                    % Add zeros to the top if the view is expanding
                    padding = zeros(newSamples - oldSamples, numCols);
                    app.ArduinoVoltages = [padding; app.ArduinoVoltages];
                else
                    % Remove the oldest data from the top if the view is shrinking
                    app.ArduinoVoltages = app.ArduinoVoltages(end-newSamples+1:end, :);
                end
            end          
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            % Open a save file window locked to .json files
            [file, path] = uiputfile('*.json', 'Save Sensor Settings');
            
            if file == 0 
                return;
            end
            
            fullFileName = fullfile(path, file);
            
            % Attempt to save the table data. Catch errors to prevent crashes.
            try
                % Convert the table to a structure array for JSON compatibility
                tableData = app.SensorTable.Data;
                structData = table2struct(tableData);
                
                % Encode the structure into formatted JSON text
                jsonText = jsonencode(structData, 'PrettyPrint', true);
                
                % Open the file, write the text, and close it safely
                fid = fopen(fullFileName, 'w');
                if fid == -1
                    error('Could not open file for writing.');
                end
                fprintf(fid, '%s', jsonText);
                fclose(fid);
                
            catch ME
                uialert(app.UIFigure, "Could not save file: " + ME.message, "Save Error");
            end
        end

        % Button pushed function: LoadSettingsButton
        function LoadSettingsButtonPushed(app, event)
            % Open a load file window locked to .json files
            [file, path] = uigetfile('*.json', 'Load Sensor Settings');
            
            if file == 0
                return;
            end
            
            fullFileName = fullfile(path, file);
            
            % Use a try-catch block to handle invalid files gracefully
            try
                % Read the raw text from the JSON file
                jsonText = fileread(fullFileName);
                
                % Decode the JSON text back into a MATLAB structure
                structData = jsondecode(jsonText);
                
                % Convert the structure back into a table format
                newTable = struct2table(structData);

                % --- ERROR CHECK 1: File Format ---
                % Ensure the loaded file has exactly the right columns
                expectedCols = {'Pin', 'Name', 'Unit', 'Scale', 'Offset', 'Enable'};
                actualCols = newTable.Properties.VariableNames;
                
                if ~isequal(expectedCols, actualCols)
                    error('Invalid file format. The parameters do not match the expected layout.');
                end

                % --- ERROR CHECK 2: Hardware Mismatch ---
                % Verify the pins in the file match the connected hardware
                if ~isempty(app.ArduinoConnection)
                    connectedPins = app.AllPins;
                    
                    % Convert the file's pin column into a standard text list
                    filePins = cellstr(newTable.Pin); 
                    
                    % Sort both lists to verify the contents are identical
                    if ~isequal(sort(connectedPins), sort(filePins))
                        error('The pins in this file do not match the connected Arduino hardware.');
                    end
                end

                % Replace the UI table data if all checks pass
                app.SensorTable.Data = newTable;

            catch ME
                uialert(app.UIFigure, "Failed to load settings: " + ME.message, "Load Error");
            end
        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
            % Toggle the timer on or off without disconnecting hardware
            buttonState = app.PauseButton.Value;
            
            if ~isempty(app.PollingTimer) && isvalid(app.PollingTimer)
                if buttonState % Button is pressed
                    stop(app.PollingTimer);
                    app.PauseButton.Text = '►';
                else % Button is unpressed
                    start(app.PollingTimer);
                    app.PauseButton.Text = '||';
                end
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Figure')
            xlabel(app.UIAxes, 'Samples')
            ylabel(app.UIAxes, 'Voltage')
            zlabel(app.UIAxes, 'Z')
            app.NotConnectedLampLabel = uilabel(app.UIFigure);
            app.NotConnectedLampLabel.Text = 'Not Connected';
            app.NotConnectedLamp = uilamp(app.UIFigure);
            app.ConnectButton = uibutton(app.UIFigure, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.HarryRogersAppLabel = uilabel(app.UIFigure);
            app.HarryRogersAppLabel.Text = 'Harry Rogers App';
            app.NumberofSamplesLabel = uilabel(app.UIFigure);
            app.NumberofSamplesLabel.Text = 'Number of Samples';
            app.NumberofSamplesSpinner = uispinner(app.UIFigure);
            app.NumberofSamplesSpinner.Step = 10;
            app.NumberofSamplesSpinner.Limits = [10 1000];
            app.NumberofSamplesSpinner.RoundFractionalValues = 'on';
            app.NumberofSamplesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofSamplesSpinnerValueChanged, true);
            app.NumberofSamplesSpinner.Value = 100;
            app.SampleRateSpinnerLabel = uilabel(app.UIFigure);
            app.SampleRateSpinnerLabel.Text = 'Sample Rate';
            app.SampleRateSpinner = uispinner(app.UIFigure);
            app.SampleRateSpinner.Step = 0.01;
            app.SampleRateSpinner.Limits = [0.01 5];
            app.SampleRateSpinner.ValueChangedFcn = createCallbackFcn(app, @SampleRateSpinnerValueChanged, true);
            app.SampleRateSpinner.Value = 1;
            app.SensorTable = uitable(app.UIFigure);
            app.SensorTable.ColumnName = {'Pin'; 'Name'; 'Unit'; 'Scale'; 'Offset'; 'Enable'};
            app.SensorTable.RowName = {};
            app.SensorTable.ColumnEditable = true;
            app.SaveSettingsButton = uibutton(app.UIFigure, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.LoadSettingsButton = uibutton(app.UIFigure, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Text = 'Load Settings';
            app.PauseButton = uibutton(app.UIFigure, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Text = 'Pause Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end