classdef FinalApp3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DataCollectionSettingsPanel  matlab.ui.container.Panel
        NumberofSamplesSpinner       matlab.ui.control.Spinner
        NumberofSamplesLabel         matlab.ui.control.Label
        SampleRateHzSpinner          matlab.ui.control.Spinner
        SampleRateSLabel             matlab.ui.control.Label
        SettingsManagementPanel      matlab.ui.container.Panel
        SaveSettingsButton           matlab.ui.control.Button
        LoadSettingsButton           matlab.ui.control.Button
        PauseButton                  matlab.ui.control.StateButton
        ConnectionPanel              matlab.ui.container.Panel
        StatusLabel                  matlab.ui.control.Label
        ConnectButton                matlab.ui.control.StateButton
        DisconnectedLamp             matlab.ui.control.Lamp
        DisconnectedLampLabel        matlab.ui.control.Label
        SensorTable                  matlab.ui.control.Table
        ArduinoAppLabel              matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Assignment Requirements
    % 1. Connection: Managed in ConnectButtonValueChanged.
    % 2. Sensor values: Calculated in DoPolling & SyncSettings.
    % 3. Settings: Saved and loaded in SaveSettings/LoadSettings.
    % 4. Sampling: Spinners update the timer and graph.
    % 5. Signals: Toggled in PauseButton & DoPolling.
    % 6. Errors: Handled by try-catch blocks.
    % 7. Shutdown: Cleanup performed in UIFigureCloseRequest.
    % 8. Code: Follows conventions with explanations.

    properties (Access = private)
        % Variables for app state
        ArduinoConnection  % Link to hardware
        PollingTimer       % Timer for reading data
        ArduinoVoltages    % History of voltage readings
        NumSamples         % Points shown on graph
        PinLines           % Line objects on graph
        PinColours         % Values for line colour
        AllPins            % List of analog pins
        SensorCache        % Copy of table data
    end

    methods (Access = private)
        function SyncSettings(app)
            % Converts table data to struct array.
            %
            % Note: Cache table settings to avoid reading the UI in the loop.
            % This prevents lag during polling.
            app.SensorCache = table2struct(app.SensorTable.Data);

            % Update Y-axis label.
            if ~isempty(app.SensorCache)
                ylabel(app.UIAxes, app.SensorCache(1).Unit);
            end
        end
        function DoPolling(app, ~, ~)
            % Timer updates the graph.
            %
            % Note: Use try-catch to stop crashes if hardware disconnects.
            try
                % Stop if no hardware.
                if isempty(app.ArduinoConnection), return; end

                % 1. Shift data
                % Move data up to make space for new readings.
                app.ArduinoVoltages(1:end-1, :) = app.ArduinoVoltages(2:end, :);

                % 2. Read hardware
                for k = 1:numel(app.AllPins)
                    if app.SensorCache(k).Enable
                        app.ArduinoVoltages(end, k) = readVoltage(app.ArduinoConnection, app.AllPins{k});
                    end
                end

                % 3. Update graphics
                legendNames   = {};
                legendHandles = gobjects(0);

                for k = 1:numel(app.AllPins)
                    config = app.SensorCache(k);
                    if config.Enable
                        % Get recent samples.
                        recentData = app.ArduinoVoltages(end - app.NumSamples + 1:end, k);

                        % Apply math: y = (Voltage * Scale) + Offset
                        scaledData = (recentData * config.Scale) + config.Offset;

                        % Set line data and visibility.
                        set(app.PinLines(k), 'YData', scaledData, 'Visible', 'on');

                        % Add to legend.
                        legendHandles(end+1) = app.PinLines(k); %#ok<AGROW>
                        legendNames{end+1}   = sprintf('%s (%s)', config.Name, config.Unit);
                    else
                        % Hide line.
                        set(app.PinLines(k), 'Visible', 'off');
                    end
                end

                if ~isempty(legendNames)
                    legend(app.UIAxes, legendHandles, legendNames, 'Location', 'northwest');
                else
                    legend(app.UIAxes, 'off');
                end

                % Note: Limit refresh rate to maintain performance.
                drawnow limitrate
            catch ME
                % Stop timer on error.
                if isvalid(app.PollingTimer)
                    stop(app.PollingTimer);
                end
                uialert(app.UIFigure, "Error: " + ME.message, "Connection Lost");

                % Reset UI elements.
                app.ConnectButton.Value = 0;
                app.ConnectButton.Text = 'Connect';
                app.DisconnectedLamp.Color = [1 0 0];
                app.DisconnectedLampLabel.Text = 'Disconnected';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Runs once when the app opens.
            % Design Decision: The UI asks for Sample Rate in Hz to match the
            % assignment brief. However, the MATLAB timer requires a 'Period'
            % in seconds. I used the equation (1 / Rate) to convert the user's
            % frequency (Hz) into a time delay (seconds) for the background clock.
            app.NumSamples = app.NumberofSamplesSpinner.Value;
            app.PollingTimer = timer("ExecutionMode", "fixedRate", ...
                "Period", 1 / app.SampleRateHzSpinner.Value, ...
                "TimerFcn", @app.DoPolling);
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            % Connects or disconnects the Arduino when the button is clicked.

            if app.ConnectButton.Value % If the toggle button is pressed IN (Connect)
                try
                    app.ConnectButton.Text = 'Connecting...';

                    % Establish the hardware connection.
                    app.ArduinoConnection = arduino;

                    % Find out how many analog pins this specific board has.
                    pins = app.ArduinoConnection.AvailableAnalogPins';
                    numPins = numel(pins);
                    app.AllPins = pins;

                    % Fill the settings table with default values based on the pin count.
                    % repmat() repeats the words 'Sensor' and 'Measurement' to fill the column.
                    % ones() and zeros() create arrays of 1s and 0s for the scale and offset.
                    app.SensorTable.Data = table(pins, repmat({'Sensor'}, numPins, 1), ...
                        repmat({'Measurement'}, numPins, 1), ones(numPins, 1), zeros(numPins, 1), ...
                        true(numPins, 1), 'VariableNames', {'Pin', 'Name', 'Unit', 'Scale', 'Offset', 'Enable'});

                    % Save these default settings to the background cache.
                    app.SyncSettings();

                    % Design Decision (Pre-allocation): I setup the data array and all
                    % graph lines during connection. Changing array sizes dynamically
                    % slows MATLAB down. Setting up a "blank canvas" here prevents lag later.
                    app.NumSamples = app.NumberofSamplesSpinner.Value;

                    % Creates a array filled with zeros. The height is the sample count, width is pin count.
                    app.ArduinoVoltages = zeros(app.NumSamples, numPins);

                    % Design Decision (Colours): I use the built-in lines() function to
                    % generate distinct line colours automatically. This ensures the app
                    % still works flawlessly even if a board with more pins is connected.
                    app.PinColours = lines(numPins);

                    % Create invisible plot lines for every pin with both XData
                    % and YData explicitly set to the correct length. In the original code,
                    % only YData (as nan) was provided, so XData defaulted to a scalar [1].
                    % When DoPolling later set YData to a full vector, MATLAB threw a
                    % dimension mismatch error. Setting XData = 1:NumSamples upfront
                    % ensures both axes are always the same length.
                    hold(app.UIAxes, 'on')
                    app.PinLines = gobjects(1, numPins);
                    for k = 1:numPins
                        app.PinLines(k) = plot(app.UIAxes, 1:app.NumSamples, nan(app.NumSamples, 1), ...
                            'LineWidth', 1.5, 'Color', app.PinColours(k,:), 'Visible', 'off');
                    end
                    hold(app.UIAxes, 'off')

                    % Fix the X-axis range so the graph doesn't auto-rescale on every update.
                    app.UIAxes.XLim = [1, app.NumSamples];

                    % Update UI to show succesful connection.
                    app.DisconnectedLamp.Color = [0 1 0];
                    app.DisconnectedLampLabel.Text = 'Connected';
                    app.ConnectButton.Text = 'Disconnect';

                    % Start reading data.
                    start(app.PollingTimer);

                catch ME
                    % Reset UI and show error if connection fails.
                    app.ConnectButton.Value = 0;
                    app.ConnectButton.Text = 'Connect';
                    uialert(app.UIFigure, "Connection Failed: " + ME.message, "Hardware Error");
                end
            else
                % If the button is un-pressed (Disconnect)
                stop(app.PollingTimer);

                % Clear the hardware connection entirely to free up the USB port.
                clear app.ArduinoConnection;
                app.ArduinoConnection = [];

                % Design Decision: Clear the graph and background data immediately
                % so old readings are not left on the screen when disconnected.
                % cla() stands for 'clear axes' and deletes the physical plot lines.
                cla(app.UIAxes);
                legend(app.UIAxes, 'off');
                app.ArduinoVoltages = [];
                app.PinLines = [];

                % Update UI to show disconnection.
                app.DisconnectedLamp.Color = [1 0 0];
                app.DisconnectedLampLabel.Text = 'Disconnected';
                app.ConnectButton.Text = 'Connect';
            end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Runs when the user clicks the 'X' to close the app window.
            % It is critical to clean up memory here so MATLAB doesn't crash.

            % 1. Stop and delete the background timer if it exists.
            if ~isempty(app.PollingTimer) && isvalid(app.PollingTimer)
                stop(app.PollingTimer);
                delete(app.PollingTimer);
            end

            % 2. Disconnect the hardware.
            if ~isempty(app.ArduinoConnection)
                clear app.ArduinoConnection;
            end

            % Remove the close callback before deleting the app. Without this,
            % delete(app) below calls the delete() method which calls delete(app.UIFigure),
            % which fires UIFigureCloseRequest a second time, causing a double-free crash.
            app.UIFigure.CloseRequestFcn = '';

            % 3. Actually delete the app window object.
            delete(app);

        end

        % Value changed function: SampleRateHzSpinner
        function SampleRateHzSpinnerValueChanged(app, event)
            % Updates the timer speed when the user changes the sample rate.
            newRate = app.SampleRateHzSpinner.Value;

            % The original code used ~isempty() to check the timer, but a
            % timer object is never "empty" — isempty() always returns false for it.
            % The correct check is isvalid(), which returns false if the timer has
            % been deleted (e.g. during app shutdown).
            if isvalid(app.PollingTimer)
                % strcmp (string compare) checks if the timer's 'Running' property is set to 'on'.
                wasRunning = strcmp(app.PollingTimer.Running, 'on');

                % The timer must be paused before changing its speed, otherwise MATLAB throws an error.
                if wasRunning
                    stop(app.PollingTimer);
                end

                % Apply the new speed by converting Hz to seconds (1 / Frequency)
                app.PollingTimer.Period = 1 / newRate;

                % Restart it if it was previously running.
                if wasRunning
                    start(app.PollingTimer);
                end
            end
        end

        % Value changed function: NumberofSamplesSpinner
        function NumberofSamplesSpinnerValueChanged(app, event)
            % Changes how much data is shown on the graph at once.
            newSamples = app.NumberofSamplesSpinner.Value;
            oldSamples = app.NumSamples;

            % Always update NumSamples so the stored value stays in sync
            % with the spinner, even when no hardware is connected.
            app.NumSamples = newSamples;

            % Only resize the data array and update the graph lines if the
            % Arduino is actually connected. Attempting this without a live
            % connection would error because ArduinoVoltages and PinLines are empty.
            if ~isempty(app.ArduinoVoltages)
                numCols = size(app.ArduinoVoltages, 2);

                if newSamples > oldSamples
                    % Add empty rows to the top if the user wants to see more data.
                    padding = zeros(newSamples - oldSamples, numCols);
                    app.ArduinoVoltages = [padding; app.ArduinoVoltages];
                else
                    % Cut off the oldest data if the user wants to see less.
                    app.ArduinoVoltages = app.ArduinoVoltages(end - newSamples + 1:end, :);
                end

                % Update the XData for every line to match the new sample count,
                % and reset the X-axis limits to fit the new window size exactly.
                if ~isempty(app.PinLines)
                    for k = 1:numel(app.PinLines)
                        app.PinLines(k).XData = 1:newSamples;
                    end
                    app.UIAxes.XLim = [1, newSamples];
                end
            end
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            % Opens a window to save the table settings to a file.
            [file, path] = uiputfile('*.json', 'Save Settings');

            % file == 0 means the user clicked 'Cancel' on the save window, so I just return.
            if file == 0, return; end

            try
                % Design Decision: I chose JSON because it is highly standard and
                % human-readable. If a user needs to check or edit the sensor config
                % outside of MATLAB, a JSON file makes this very easy.
                % jsonencode turns the table structure into a formatted text string.
                jsonText = jsonencode(table2struct(app.SensorTable.Data), 'PrettyPrint', true);

                % Write the text string into the actual file.
                fid = fopen(fullfile(path, file), 'w');
                fprintf(fid, '%s', jsonText);
                fclose(fid);
            catch ME
                uialert(app.UIFigure, "Save Failed: " + ME.message, "File Error");
            end
        end

        % Button pushed function: LoadSettingsButton
        function LoadSettingsButtonPushed(app, event)
            % Opens a window to load table settings from a file.
            [file, path] = uigetfile('*.json', 'Load Settings');
            if file == 0, return; end

            try
                % jsondecode takes the raw text from the file and turns it back into a MATLAB struct.
                data = jsondecode(fileread(fullfile(path, file)));

                % struct2table converts that struct back into a table format for the UI.
                newTable = struct2table(data);

                % Requirement check: Ensure the loaded pins match the actual hardware.
                if ~isempty(app.ArduinoConnection)
                    % I use sort() to put both lists of pins in alphabetical order.
                    % cellstr() ensures the table data is in the exact same text format as the hardware list.
                    % isequal() checks if the two lists match exactly. If not, it throws a manual error.
                    if ~isequal(sort(app.AllPins), sort(cellstr(newTable.Pin)))
                        error('File pins do not match connected hardware.');
                    end
                end

                % Update the UI table and background cache.
                app.SensorTable.Data = newTable;
                app.SyncSettings();
            catch ME
                uialert(app.UIFigure, "Load Failed: " + ME.message, "Format Error");
            end
        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
            % Pauses or plays the graph updates without disconnecting the hardware.
            buttonState = app.PauseButton.Value;

            % Added a check for an active Arduino connection before
            % allowing the timer to start. In the original code, pressing Play
            % while disconnected would fire the timer immediately, causing DoPolling
            % to run with no hardware and crash into the catch block.
            if isvalid(app.PollingTimer)
                if buttonState
                    stop(app.PollingTimer);
                    app.PauseButton.Text = 'Play';
                elseif ~isempty(app.ArduinoConnection)
                    % Only restart the timer if hardware is actually connected.
                    start(app.PollingTimer);
                    app.PauseButton.Text = 'Pause';
                end
            end
        end

        % Cell edit callback: SensorTable
        function SensorTableCellEdit(app, event)
            % Updates the background cache immediately when the user types in the table.
            app.SyncSettings();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Arduino Pin Reading')
            xlabel(app.UIAxes, 'Samples')
            ylabel(app.UIAxes, 'Measurement')
            zlabel(app.UIAxes, 'Z')
            app.ArduinoAppLabel = uilabel(app.UIFigure);
            app.ArduinoAppLabel.Text = 'Arduino App';
            app.SensorTable = uitable(app.UIFigure);
            app.SensorTable.ColumnName = {'Pin'; 'Name'; 'Unit'; 'Scale'; 'Offset'; 'Enable'};
            app.SensorTable.RowName = {};
            app.SensorTable.ColumnEditable = true;
            app.SensorTable.CellEditCallback = createCallbackFcn(app, @SensorTableCellEdit, true);
            app.ConnectionPanel = uipanel(app.UIFigure);
            app.ConnectionPanel.Title = 'Connection';
            app.DisconnectedLampLabel = uilabel(app.ConnectionPanel);
            app.DisconnectedLampLabel.Text = 'Disconnected';
            app.DisconnectedLamp = uilamp(app.ConnectionPanel);
            app.ConnectButton = uibutton(app.ConnectionPanel, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.StatusLabel = uilabel(app.ConnectionPanel);
            app.StatusLabel.Text = 'Status:';
            app.SettingsManagementPanel = uipanel(app.UIFigure);
            app.SettingsManagementPanel.Title = 'Settings Management';
            app.PauseButton = uibutton(app.SettingsManagementPanel, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Text = 'Pause Plot';
            app.LoadSettingsButton = uibutton(app.SettingsManagementPanel, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Text = 'Load Settings';
            app.SaveSettingsButton = uibutton(app.SettingsManagementPanel, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.DataCollectionSettingsPanel = uipanel(app.UIFigure);
            app.DataCollectionSettingsPanel.Title = 'Data Collection Settings';
            app.SampleRateSLabel = uilabel(app.DataCollectionSettingsPanel);
            app.SampleRateSLabel.Text = 'Sample Rate (Hz):';
            app.SampleRateHzSpinner = uispinner(app.DataCollectionSettingsPanel);
            app.SampleRateHzSpinner.Step = 0.01;
            app.SampleRateHzSpinner.Limits = [0.1 20];
            app.SampleRateHzSpinner.ValueChangedFcn = createCallbackFcn(app, @SampleRateHzSpinnerValueChanged, true);
            app.SampleRateHzSpinner.Value = 1;
            app.NumberofSamplesLabel = uilabel(app.DataCollectionSettingsPanel);
            app.NumberofSamplesLabel.Text = 'Number of Samples:';
            app.NumberofSamplesSpinner = uispinner(app.DataCollectionSettingsPanel);
            app.NumberofSamplesSpinner.Step = 10;
            app.NumberofSamplesSpinner.Limits = [10 1000];
            app.NumberofSamplesSpinner.RoundFractionalValues = 'on';
            app.NumberofSamplesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofSamplesSpinnerValueChanged, true);
            app.NumberofSamplesSpinner.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalApp3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end