classdef HarryRogers_22835293_EO631_GUI_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DataCollectionSettingsPanel  matlab.ui.container.Panel
        NumberofSamplesSpinner       matlab.ui.control.Spinner
        NumberofSamplesLabel         matlab.ui.control.Label
        SampleRateHzSpinner          matlab.ui.control.Spinner
        SampleRateSLabel             matlab.ui.control.Label
        SettingsManagementPanel      matlab.ui.container.Panel
        SaveSettingsButton           matlab.ui.control.Button
        LoadSettingsButton           matlab.ui.control.Button
        PauseButton                  matlab.ui.control.StateButton
        ConnectionPanel              matlab.ui.container.Panel
        StatusLabel                  matlab.ui.control.Label
        ConnectButton                matlab.ui.control.StateButton
        DisconnectedLamp             matlab.ui.control.Lamp
        DisconnectedLampLabel        matlab.ui.control.Label
        SensorTable                  matlab.ui.control.Table
        ArduinoAppLabel              matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % =========================================================================
    % AI ASSISTANCE DECLARATION
    % Generative AI Tool used: Google Gemini (Amber Rating Compliant)
    % I have used Gemini Ai to help get the right MATLAB commands for things 
    % I know how to do but in python. See "AI Gemini:" for all uses.
    % =========================================================================
        

    properties (Access = private) % Define variables hidden from outside functions
        ArduinoConnection  % Link to the physical Arduino board
        PollingTimer       % Timer object triggering the data read loop
        ArduinoVoltages    % Buffer of raw voltage readings
        NumSamples         % Number of data points displayed on the graph
        PinLines           % Array of graph line objects for each physical pin
        PinColours         % RGB colour array for the plot lines
        AllPins            % Cell array of available analogue pins on the board
        SensorCache        % Cached struct of the UI table data for fast access
    end

    methods (Access = private) % Define functions hidden from outside the app
        function SyncSettings(app) % Function to update the sensor cache
            % Convert UI table data to a struct array and cache it in memory to prevent polling lag.
            app.SensorCache = table2struct(app.SensorTable.Data); % Read UI table and save to struct

        end

        function DoPolling(app, ~, ~) % Timer function to read hardware and update the graph
            try % Attempt to run the polling loop, catching any fatal errors
                if isempty(app.ArduinoConnection) || ~isvalid(app.PollingTimer) % Check if hardware is disconnected or timer is invalid
                    return; % Exit the function immediately if checks fail
                end 

                % Shift all existing rows up by one index to free the final row.
                app.ArduinoVoltages(1:end-1, :) = app.ArduinoVoltages(2:end, :); % Overwrite old data with shifted recent data % AI Gemini: "What is the MATLAB equivalent of Python's array shifting arr[:-1] = arr[1:] to avoid dimension errors?"

                for k = 1:numel(app.AllPins) % Loop through every available pin on the board
                    app.ArduinoVoltages(end, k) = readVoltage(app.ArduinoConnection, app.AllPins{k}); % Read current voltage and store in the last row
                end 

                legendNames   = {}; % Initialise an empty cell array for legend text
                legendHandles = []; % Initialise an empty array for legend lines

                for k = 1:numel(app.AllPins) % Loop through all pins to update graphics
                    config = app.SensorCache(k); % Retrieve the cached settings for the current pin

                    if config.Enable % Check if the user enabled this pin in the UI
                        recentData = app.ArduinoVoltages(end - app.NumSamples + 1:end, k); % Extract the most recent data points based on the window size
                        scaledData = (recentData * config.Scale) + config.Offset; % Apply user-defined scaling and offset mathematics

                        set(app.PinLines(k), 'YData', scaledData, 'XData', (1:numel(scaledData)).', 'Visible', 'on'); % Update line coordinates and make it visible


                        legendHandles(end+1) = app.PinLines(k); % Add this active sensor's graph line to the next available spot in our legend list

                        % Combine the sensor's Name and Unit into a single formatted text string (e.g., 'Temperature (Celsius)').
                        % The sprintf function injects the variables into the '%s' placeholders in the template.
                        % This new string is then appended to the end of the legendNames cell array.
                        legendNames{end+1} = sprintf('%s (%s)', config.Name, config.Unit); % AI Gemini: "What is the MATLAB equivalent of Python's f-string to combine these two variables?"
                    else % If the pin is disabled in the UI
                        set(app.PinLines(k), 'Visible', 'off'); % Hide the specific line from the graph
                    end
                end 

                if ~isempty(legendNames) % Check if any lines are currently active
                    legend(app.UIAxes, legendHandles, legendNames, 'Location', 'northwest'); % Draw the legend in the top left corner
                else % If no lines are active
                    legend(app.UIAxes, 'off'); % Hide the legend completely
                end 

                % Update the graph on the screen immediately.
                % Design Decision: The 'limitrate' command acts as a speed limit for the graphics. 
                % If the Arduino sends data faster than the screen can draw it, MATLAB will 
                % skip updating the screen for a fraction of a second. This stops the app from 
                % freezing and ensures never miss reading the actual sensor data.
                drawnow limitrate

            catch ME % Catch any errors generated during the try block
                if isvalid(app.PollingTimer) % Check if the timer is still running
                    stop(app.PollingTimer); % Stop the timer to prevent continuous error looping
                end 

                uialert(app.UIFigure, "Hardware error: " + ME.message, "Connection Lost"); % Alert the user with the specific error message
                app.ConnectButton.Value = 0; % Un-press the connect toggle button
                app.ConnectButton.Text = 'Connect'; % Reset the button text
                app.DisconnectedLamp.Color = [1 0 0]; % Change the status lamp to red
                app.DisconnectedLampLabel.Text = 'Disconnected'; % Update the status lamp text

            end 
        end 
    end 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Runs once when the app opens.
            % Design Decision: The UI asks for Sample Rate in Hz to match the
            % assignment brief. However, the MATLAB timer requires a 'Period'
            % in seconds. I used the equation (1 / Rate) to convert the user's
            % frequency (Hz) into a time delay (seconds) for the background clock.
            % Set the BusyMode to 'drop' so that if graphics lag behind
            % hardware reads, it simply skips a frame rather than queuing up
            % a backlog of requests and crashing the Arduino connection.
            app.NumSamples = app.NumberofSamplesSpinner.Value;
            app.PollingTimer = timer("ExecutionMode", "fixedRate", ...
                "Period", round(1 / app.SampleRateHzSpinner.Value, 3), ...
                "BusyMode", "drop", ...
                "TimerFcn", @app.DoPolling);
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            % Connects or disconnects the Arduino when the button is clicked.
            if app.ConnectButton.Value % Check if the connect button is pressed IN
                try % Attempt to establish the hardware connection
                    app.ConnectButton.Text = 'Connecting...'; % Update button text to show progress

                    app.ArduinoConnection = arduino; % Initialise the connection to the physical Arduino

                    pins = app.ArduinoConnection.AvailableAnalogPins'; % Query the board for available analog pins and transpose to a row
                    numPins = numel(pins); % Count the total number of analog pins found
                    app.AllPins = pins; % Store the pin list in the background property

                    % Populate the settings table with default configurations, creating arrays of 1s, 0s, and default text.
                    app.SensorTable.Data = table(pins, repmat({'Sensor'}, numPins, 1), ...
                        repmat({'Measurement'}, numPins, 1), ones(numPins, 1), zeros(numPins, 1), ...
                        true(numPins, 1), 'VariableNames', {'Pin', 'Name', 'Unit', 'Scale', 'Offset', 'Enable'});

                    app.SyncSettings(); % Cache these default settings into memory for the polling loop

                    app.NumSamples = app.NumberofSamplesSpinner.Value; % Read the desired number of sample size from the UI spinner

                    app.ArduinoVoltages = nan(1000, numPins); % Allocate a 1000-sample history buffer filled with NaNs

                    % Generate an array of distinct RGB colours for the plot lines.
                    % Design Decision: Using the built-in lines() function
                    % to get the colour to match the exact number of connected pins, avoiding
                    % the need to hardcode a fixed array of RGB values.
                    app.PinColours = lines(numPins);

                    hold(app.UIAxes, 'on') % Keep existing plots on the axes so new lines are added, not replaced
                    app.PinLines = gobjects(1, numPins); % Pre-allocate an array for the graphics objects

                    for k = 1:numPins % Loop through each pin to create its plot line
                        % Initialise invisible plot lines with explicit X and Y lengths to prevent dimension mismatch errors
                        app.PinLines(k) = plot(app.UIAxes, 1:app.NumSamples, nan(app.NumSamples, 1), ...
                            'LineWidth', 1.5, 'Color', app.PinColours(k,:), 'Visible', 'off'); % Create the line object
                    end 

                    hold(app.UIAxes, 'off') % Release the axes hold state

                    app.UIAxes.XLim = [1, app.NumSamples]; % Lock the X-axis range to match the number of samples app.UIAxes.XLim = [1, newSamples]; % AI Gemini: "How do I dynamically set the X-axis limits in MATLAB App Designer?"
                    app.DisconnectedLamp.Color = [0 1 0]; % Change the status lamp to green
                    app.DisconnectedLampLabel.Text = 'Connected'; % Update the status lamp text to confirm connection
                    app.ConnectButton.Text = 'Disconnect'; % Update the button text to allow disconnection

                    start(app.PollingTimer); % Trigger the background polling loop to begin reading data

                catch ME % Catch any errors if the connection fails
                    app.ConnectButton.Value = 0; % Un-press the connect toggle button
                    app.ConnectButton.Text = 'Connect'; % Reset the button text
                    uialert(app.UIFigure, "Connection Failed: " + ME.message, "Hardware Error"); % Display an error dialogue with the failure reason
                end 
            else % If the button is un-pressed (triggering a disconnect)
                stop(app.PollingTimer); % Halt the background polling timer immediately

                clear app.ArduinoConnection; % Clear the Arduino object from memory to release the USB port
                app.ArduinoConnection = []; % Reset the property to an empty array

                cla(app.UIAxes); % Clear all physical plot lines from the graph axes
                legend(app.UIAxes, 'off'); % Turn off and hide the legend
                app.ArduinoVoltages = []; % Wipe the voltage data array to remove stale readings
                app.PinLines = []; % Wipe the graphics objects array

                app.DisconnectedLamp.Color = [1 0 0]; % Change the status lamp back to red
                app.DisconnectedLampLabel.Text = 'Disconnected'; % Update the status lamp text to confirm disconnection
                app.ConnectButton.Text = 'Connect'; % Reset the button text to its default state
            end 

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Runs when the user clicks the 'X' to close the app window.
            % It is critical to clean up memory here so MATLAB doesn't crash.

            if ~isempty(app.PollingTimer) && isvalid(app.PollingTimer) % Check if a valid timer object exists in memory
                stop(app.PollingTimer); % Halt the timer to stop background executions
                delete(app.PollingTimer); % Remove the timer object from memory safely
            end 

            if ~isempty(app.ArduinoConnection) % Check if an active hardware connection exists
                clear app.ArduinoConnection; % Clear the connection to release the USB port
            end     

            app.UIFigure.CloseRequestFcn = ''; % Clear the close callback to prevent recursive double-free deletion loops
            delete(app); % Destroy the application object and completely close the window

        end

        % Value changed function: SampleRateHzSpinner
        function SampleRateHzSpinnerValueChanged(app, event)
            % Updates the timer speed when the user changes the sample rate.
            newRate = app.SampleRateHzSpinner.Value;

            % check is isvalid(), which returns false if the timer has
            % been deleted (e.g. during app shutdown).
            if isvalid(app.PollingTimer)
                % strcmp (string compare) checks if the timer's 'Running' property is set to 'on'.
                wasRunning = strcmp(app.PollingTimer.Running, 'on');

                % The timer must be paused before changing its speed, otherwise MATLAB throws an error.
                if wasRunning
                    stop(app.PollingTimer);
                end

                % Apply the new speed by converting Hz to seconds (1 / Frequency)
                app.PollingTimer.Period = round(1 / newRate, 3);

                % If the timer was running before the frequency change
                if wasRunning
                    start(app.PollingTimer);% Restart the timer to resume polling
                end
            end
        end

        % Value changed function: NumberofSamplesSpinner
        function NumberofSamplesSpinnerValueChanged(app, event)
            % Read the requested number of data points to display from the user interface.
            newSamples = app.NumberofSamplesSpinner.Value;

            % Design Decision: Temporarily pause the background timer before updating the graph.
            % This ensures the app does not attempt to read new hardware data at the exact
            % same moment resizing the display window, preventing errors.
            wasRunning = false;

            if isvalid(app.PollingTimer)
                % Safely check if the timer is currently active.
                % use strcmp because the standard '==' operator can cause matrix dimension
                % errors when comparing text strings of different lengths (like 'on' and 'off').
                wasRunning = strcmp(app.PollingTimer.Running, 'on');

                if wasRunning
                    stop(app.PollingTimer);
                end
            end

            % Update the stored sample count
            app.NumSamples = newSamples;

            % Immediately update the graph to reflect the new slice of the 1000-sample buffer
            if ~isempty(app.ArduinoVoltages) && ~isempty(app.PinLines)
                for k = 1:numel(app.PinLines)
                    % Only process lines that are currently enabled in the table
                    if app.SensorCache(k).Enable
                        % Extract the new window size from the very bottom of the fixed buffer
                        recentData = app.ArduinoVoltages(end - app.NumSamples + 1:end, k);

                        % Apply the scaling so the visual update is accurate
                        scaledData = (recentData * app.SensorCache(k).Scale) + app.SensorCache(k).Offset;

                        % Push the new slice to the graph, updating X and Y simultaneously
                        set(app.PinLines(k), 'XData', (1:newSamples).', 'YData', scaledData);
                    end
                end

                % Fix the X-axis range to exactly match the new number of
                % samples
                app.UIAxes.XLim = [1, newSamples];
            end

            % Restart the clock if it was running
            if wasRunning
                start(app.PollingTimer);
            end
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButtonPushed(app, event)
            % Opens a window to save the table settings to a file.
            [file, path] = uiputfile('*.json', 'Save Settings');

            % file == 0 means the user clicked 'Cancel' on the save window, so just return.
            if file == 0, return; end

            try
                % Design Decision: chose JSON because it is highly standard and
                % human-readable. If a user needs to check or edit the sensor config
                % outside of MATLAB, a JSON file makes this very easy.
                % jsonencode turns the table structure into a formatted text string.

                % AI Gemini: "I am used to using json.dumps() in Python to save data structures. What is the MATLAB equivalent to convert a UI Table into a formatted JSON string and write it to a file?"
                jsonText = jsonencode(table2struct(app.SensorTable.Data), 'PrettyPrint', true); 

                % Write the text string into the actual file.
                fid = fopen(fullfile(path, file), 'w');
                fprintf(fid, '%s', jsonText);
                fclose(fid);
            catch ME
                uialert(app.UIFigure, "Save Failed: " + ME.message, "File Error");
            end
        end

        % Button pushed function: LoadSettingsButton
        function LoadSettingsButtonPushed(app, event)
            % Opens a window to load table settings from a file.
            [file, path] = uigetfile('*.json', 'Load Settings');
            if file == 0, return; end

            try
                % AI Gemini: "In Python, I would just use json.load() to read a file back into a dictionary. How do I do this in MATLAB and get the JSON data back into a table format?"     

                % jsondecode takes the raw text from the file and turns it back into a MATLAB struct.
                data = jsondecode(fileread(fullfile(path, file)));

                % struct2table converts that struct back into a table format for the UI.
                newTable = struct2table(data);

               
                if ~isempty(app.ArduinoConnection)
                    % use sort() to put both lists of pins in alphabetical order.
                    % cellstr() ensures the table data is in the exact same text format as the hardware list.
                    % isequal() checks if the two lists match exactly. If not, it throws a manual error.
                    if ~isequal(sort(app.AllPins), sort(cellstr(newTable.Pin)))
                        error('File pins do not match connected hardware.');
                    end
                end

                % Update the UI table and background cache.
                app.SensorTable.Data = newTable;
                app.SyncSettings();
            catch ME
                uialert(app.UIFigure, "Load Failed: " + ME.message, "Format Error");
            end
        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)
            % Pauses or plays the graph updates without disconnecting the hardware.
            buttonState = app.PauseButton.Value;
            % Added extra state checks to prevent firing a timer that is already running.
            if isvalid(app.PollingTimer)
                if buttonState
                    % Only attempt a stop if the timer is actively ticking
                    if strcmp(app.PollingTimer.Running, 'on')
                        stop(app.PollingTimer);
                    end
                    app.PauseButton.Text = 'Play';
                else
                    % Only attempt a start if hardware exists and the timer is stopped
                    if ~isempty(app.ArduinoConnection) && strcmp(app.PollingTimer.Running, 'off') % strcmp (string compare) checks if the timer's 'Running' property is set to 'off'.                        start(app.PollingTimer);
                    end
                    app.PauseButton.Text = 'Pause';
                end
            end
        end

        % Cell edit callback: SensorTable
        function SensorTableCellEdit(app, event)
            % Updates the background cache immediately when the user types in the table.
            app.SyncSettings();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Arduino Pin Reading')
            xlabel(app.UIAxes, 'Samples')
            ylabel(app.UIAxes, 'Measurement')
            zlabel(app.UIAxes, 'Z')
            app.ArduinoAppLabel = uilabel(app.UIFigure);
            app.ArduinoAppLabel.Text = 'Arduino App';
            app.SensorTable = uitable(app.UIFigure);
            app.SensorTable.ColumnName = {'Pin'; 'Name'; 'Unit'; 'Scale'; 'Offset'; 'Enable'};
            app.SensorTable.RowName = {};
            app.SensorTable.ColumnEditable = true;
            app.SensorTable.CellEditCallback = createCallbackFcn(app, @SensorTableCellEdit, true);
            app.ConnectionPanel = uipanel(app.UIFigure);
            app.ConnectionPanel.Title = 'Connection';
            app.DisconnectedLampLabel = uilabel(app.ConnectionPanel);
            app.DisconnectedLampLabel.Text = 'Disconnected';
            app.DisconnectedLamp = uilamp(app.ConnectionPanel);
            app.ConnectButton = uibutton(app.ConnectionPanel, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.StatusLabel = uilabel(app.ConnectionPanel);
            app.StatusLabel.Text = 'Status:';
            app.SettingsManagementPanel = uipanel(app.UIFigure);
            app.SettingsManagementPanel.Title = 'Settings Management';
            app.PauseButton = uibutton(app.SettingsManagementPanel, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Text = 'Pause Plot';
            app.LoadSettingsButton = uibutton(app.SettingsManagementPanel, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Text = 'Load Settings';
            app.SaveSettingsButton = uibutton(app.SettingsManagementPanel, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.DataCollectionSettingsPanel = uipanel(app.UIFigure);
            app.DataCollectionSettingsPanel.Title = 'Data Collection Settings';
            app.SampleRateSLabel = uilabel(app.DataCollectionSettingsPanel);
            app.SampleRateSLabel.Text = 'Sample Rate (Hz):';
            app.SampleRateHzSpinner = uispinner(app.DataCollectionSettingsPanel);
            app.SampleRateHzSpinner.Step = 0.01;
            app.SampleRateHzSpinner.Limits = [0.1 2.5];
            app.SampleRateHzSpinner.ValueChangedFcn = createCallbackFcn(app, @SampleRateHzSpinnerValueChanged, true);
            app.SampleRateHzSpinner.Value = 1;
            app.NumberofSamplesLabel = uilabel(app.DataCollectionSettingsPanel);
            app.NumberofSamplesLabel.Text = 'Number of Samples:';
            app.NumberofSamplesSpinner = uispinner(app.DataCollectionSettingsPanel);
            app.NumberofSamplesSpinner.Step = 10;
            app.NumberofSamplesSpinner.Limits = [10 1000];
            app.NumberofSamplesSpinner.RoundFractionalValues = 'on';
            app.NumberofSamplesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofSamplesSpinnerValueChanged, true);
            app.NumberofSamplesSpinner.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HarryRogers_22835293_EO631_GUI_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end