classdef HarryRogers_22835293_EO631_GUI_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        DataCollectionSettingsPanel  matlab.ui.container.Panel
        NumberofSamplesSpinner       matlab.ui.control.Spinner
        NumberofSamplesLabel         matlab.ui.control.Label
        SampleRateHzSpinner          matlab.ui.control.Spinner
        SampleRateSLabel             matlab.ui.control.Label
        SettingsManagementPanel      matlab.ui.container.Panel
        SaveSettingsButton           matlab.ui.control.Button
        LoadSettingsButton           matlab.ui.control.Button
        PauseButton                  matlab.ui.control.StateButton
        ConnectionPanel              matlab.ui.container.Panel
        StatusLabel                  matlab.ui.control.Label
        ConnectButton                matlab.ui.control.StateButton
        DisconnectedLamp             matlab.ui.control.Lamp
        DisconnectedLampLabel        matlab.ui.control.Label
        SensorTable                  matlab.ui.control.Table
        ArduinoAppLabel              matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % =========================================================================
    % AI ASSISTANCE DECLARATION & ZERO-METADATA PORTING
    % Generative AI Tool used: Google Gemini (Amber Rating Compliant)
    % Re-engineering Strategy:
    % 100% programmatic re-mapping of existing Arduino components to create
    % a visual PIC16F84A Assembly Emulator inside the standard .mlapp file!
    % 1. Controls are re-bound: SaveSettings=Execute, LoadSettings=Reset, 
    %    PauseButton=Chaser, ConnectButton=AutoDemo, Spinners=Opcode/Val.
    % 2. SensorTable is formatted as a Live Register SFR monitor.
    % 3. UIAxes programmatically draws the 18-Pin DIP and PORTB visual LEDs.
    % 4. State is stored in UIFigure.UserData to protect binary model.
    % =========================================================================
        

    properties (Access = private) % Define variables hidden from outside functions
        ArduinoConnection  % Link to the physical Arduino board (stubbed as emulator state)
        PollingTimer       % Timer object triggering the data read loop
        ArduinoVoltages    % Buffer of raw voltage readings
        NumSamples         % Number of data points displayed on the graph
        PinLines           % Array of graph line objects for each physical pin
        PinColours         % RGB colour array for the plot lines
        AllPins            % Cell array of available analogue pins on the board
        SensorCache        % Cached struct of the UI table data for fast access
    end

    methods (Access = private) % Define functions hidden from outside the app
        function SyncSettings(app) % Function to update the sensor cache
            % Table edits are handled programmatically to protect visual model
        end

        function DoPolling(app, ~, ~) % Timer function to execute automated steps (Demo/Chaser)
            try
                if ~isstruct(app.UIFigure.UserData) || ~isvalid(app.PollingTimer)
                    return;
                end 

                % Check mode: 1 = Knight Rider Chaser, 2 = Auto Demo
                mode = app.UIFigure.UserData.TimerMode;
                
                if mode == 1
                    % --- KNIGHT RIDER CHASER LOOP ---
                    val = app.UIFigure.UserData.PortB;
                    dir = app.UIFigure.UserData.ChaserDir; % 1 = Left, 2 = Right
                    
                    if val == 0 || val == 128
                        val = 1;
                        dir = 1;
                    elseif dir == 1
                        val = bitshift(val, 1);
                        if val == 128, dir = 2; end
                    else
                        val = bitshift(val, -1);
                        if val == 1, dir = 1; end
                    end
                    
                    app.UIFigure.UserData.PortB = val;
                    app.UIFigure.UserData.ChaserDir = dir;
                    
                    % Update table row 5 (PORTB)
                    app.SensorTable.Data{5, 2} = sprintf('0x%02X', val);
                    app.SensorTable.Data{5, 3} = dec2bin(val, 8);
                    app.SensorTable.Data{6, 3} = '⚡ Knight Rider chaser loop running...';
                    
                elseif mode == 2
                    % --- AUTOMATED ASSEMBLY DEMO ---
                    step = app.UIFigure.UserData.DemoStep;
                    app.UIFigure.UserData.DemoStep = step + 1;
                    
                    switch step
                        case 1
                            app.SampleRateHzSpinner.Value = 1; % MOVLW
                            app.NumberofSamplesSpinner.Value = 1;
                            executeInstruction(app);
                        case 2
                            app.SampleRateHzSpinner.Value = 2; % ADDLW
                            app.NumberofSamplesSpinner.Value = 15;
                            executeInstruction(app);
                        case 3
                            app.SampleRateHzSpinner.Value = 7; % BSF
                            app.NumberofSamplesSpinner.Value = 4; % Bit 4
                            executeInstruction(app);
                        case 4
                            app.SampleRateHzSpinner.Value = 7; % BSF
                            app.NumberofSamplesSpinner.Value = 0; % Bit 0
                            executeInstruction(app);
                        case 5
                            app.SampleRateHzSpinner.Value = 6; % XORLW
                            app.NumberofSamplesSpinner.Value = 16;
                            executeInstruction(app);
                        case 6
                            app.SampleRateHzSpinner.Value = 3; % SUBLW
                            app.NumberofSamplesSpinner.Value = 250;
                            executeInstruction(app);
                        case 7
                            app.SampleRateHzSpinner.Value = 8; % BCF
                            app.NumberofSamplesSpinner.Value = 4; % Bit 4
                            executeInstruction(app);
                        case 8
                            % Trigger automatic chaser step inside demo
                            app.UIFigure.UserData.PortB = uint8(255);
                            app.SensorTable.Data{5, 2} = '0xFF';
                            app.SensorTable.Data{5, 3} = '11111111';
                            app.SensorTable.Data{6, 3} = '🎉 Demo Complete! All pins toggled.';
                            
                            % Stop the demo timer automatically
                            stop(app.PollingTimer);
                            app.ConnectButton.Value = false;
                            app.ConnectButton.Text = '⚙ RUN AUTO DEMO';
                            app.DisconnectedLamp.Color = [0.0824 0.6275 0.3647]; % Solid Green
                            app.DisconnectedLampLabel.Text = 'Connected (Sim)';
                    end
                end

                updateDisplay(app);

            catch ME
                if isvalid(app.PollingTimer)
                    stop(app.PollingTimer);
                end
                uialert(app.UIFigure, "Timer error: " + ME.message, "Execution Error");
            end 
        end 

        % Dedicated helper to update visual board and table states
        function updateDisplay(app)
            if ~isstruct(app.UIFigure.UserData)
                return;
            end
            
            w = app.UIFigure.UserData.WReg;
            status = app.UIFigure.UserData.StatusReg;
            portb = app.UIFigure.UserData.PortB;
            
            % 1. Update Special Function Registers inside UI Table
            app.SensorTable.Data{1, 2} = sprintf('0x%02X', w);
            app.SensorTable.Data{1, 3} = dec2bin(w, 8);
            
            app.SensorTable.Data{2, 2} = sprintf('0x%02X', status);
            app.SensorTable.Data{2, 3} = dec2bin(status, 8);
            
            % Status Flags
            zFlag = bitget(status, 3); % Bit 2 = 1-indexed index 3
            cFlag = bitget(status, 1); % Bit 0 = index 1
            
            if zFlag
                app.SensorTable.Data{3, 2} = '0x04';
                app.SensorTable.Data{3, 3} = 'Active Z 🟢';
                app.DisconnectedLamp.Color = [0.0627 0.8294 0.4431]; % Green lamp indicates Zero set!
            else
                app.SensorTable.Data{3, 2} = '0x00';
                app.SensorTable.Data{3, 3} = 'Inactive 🔴';
            end
            
            if cFlag
                app.SensorTable.Data{4, 2} = '0x01';
                app.SensorTable.Data{4, 3} = 'Active C 🟡';
            else
                app.SensorTable.Data{4, 2} = '0x00';
                app.SensorTable.Data{4, 3} = 'Inactive 🔴';
            end
            
            app.SensorTable.Data{5, 2} = sprintf('0x%02X', portb);
            app.SensorTable.Data{5, 3} = dec2bin(portb, 8);
            
            % 2. Update Vector LEDs inside UIAxes programmatically
            leds = app.UIFigure.UserData.LedHandles;
            if ~isempty(leds)
                for k = 1:8
                    bitVal = bitget(portb, k);
                    if bitVal
                        set(leds(k), 'FaceColor', [0.9333 0.6392 0.1882]); % Ripe Glowing Amber!
                    else
                        set(leds(k), 'FaceColor', [0.1500 0.1800 0.2500]); % Deep inactive grey
                    end
                end
            end
            
            % 3. Update active Opcode names
            opIndex = app.SampleRateHzSpinner.Value;
            opNames = {
                'MOVLW (Move Literal to W)', ...
                'ADDLW (Add Literal to W)', ...
                'SUBLW (Subtract W from Literal)', ...
                'ANDLW (AND Literal with W)', ...
                'IORLW (OR Literal with W)', ...
                'XORLW (XOR Literal with W)', ...
                'BSF PORTB (Bit Set File)', ...
                'BCF PORTB (Bit Clear File)'
            };
            app.SensorTable.Data{6, 2} = sprintf('Opcode %d', opIndex);
            
            % Parameter Labels Update
            if opIndex >= 7
                app.NumberofSamplesLabel.Text = 'Bit Select (0 to 7):';
            else
                app.NumberofSamplesLabel.Text = 'Literal Value (0-255):';
            end
        end

        % CPU Core Instruction Executor
        function executeInstruction(app)
            op = app.SampleRateHzSpinner.Value;
            val = uint8(app.NumberofSamplesSpinner.Value);
            
            w = app.UIFigure.UserData.WReg;
            status = app.UIFigure.UserData.StatusReg;
            portb = app.UIFigure.UserData.PortB;
            
            desc = '';
            
            switch op
                case 1 % MOVLW
                    w = val;
                    desc = sprintf('MOVLW 0x%02X -> W register loaded.', val);
                case 2 % ADDLW
                    temp = double(w) + double(val);
                    cFlag = temp > 255;
                    w = uint8(mod(temp, 256));
                    zFlag = w == 0;
                    status = bitset(status, 1, cFlag);
                    status = bitset(status, 3, zFlag);
                    desc = sprintf('ADDLW 0x%02X -> W = 0x%02X. Flags: Z=%d, C=%d', val, w, zFlag, cFlag);
                case 3 % SUBLW (k - W)
                    temp = double(val) - double(w);
                    cFlag = temp >= 0;
                    w = uint8(mod(temp, 256));
                    zFlag = w == 0;
                    status = bitset(status, 1, cFlag);
                    status = bitset(status, 3, zFlag);
                    desc = sprintf('SUBLW 0x%02X -> W = 0x%02X. Flags: Z=%d, C=%d', val, w, zFlag, cFlag);
                case 4 % ANDLW
                    w = bitand(w, val);
                    zFlag = w == 0;
                    status = bitset(status, 3, zFlag);
                    desc = sprintf('ANDLW 0x%02X -> W = 0x%02X. Flags: Z=%d', val, w, zFlag);
                case 5 % IORLW
                    w = bitor(w, val);
                    zFlag = w == 0;
                    status = bitset(status, 3, zFlag);
                    desc = sprintf('IORLW 0x%02X -> W = 0x%02X. Flags: Z=%d', val, w, zFlag);
                case 6 % XORLW
                    w = bitxor(w, val);
                    zFlag = w == 0;
                    status = bitset(status, 3, zFlag);
                    desc = sprintf('XORLW 0x%02X -> W = 0x%02X. Flags: Z=%d', val, w, zFlag);
                case 7 % BSF
                    bitNum = double(val);
                    if bitNum > 7, bitNum = 7; end
                    portb = bitset(portb, bitNum + 1, 1);
                    desc = sprintf('BSF PORTB, %d -> Glowing pin LED RB%d set.', bitNum, bitNum);
                case 8 % BCF
                    bitNum = double(val);
                    if bitNum > 7, bitNum = 7; end
                    portb = bitset(portb, bitNum + 1, 0);
                    desc = sprintf('BCF PORTB, %d -> Pin LED RB%d cleared.', bitNum, bitNum);
            end
            
            app.UIFigure.UserData.WReg = w;
            app.UIFigure.UserData.StatusReg = status;
            app.UIFigure.UserData.PortB = portb;
            
            app.SensorTable.Data{6, 3} = desc;
        end
    end 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 1. Core Figure Setup (Stealth Dark Navy Theme)
            app.UIFigure.Color = [0.0706 0.0941 0.1412]; % #121824
            app.UIFigure.Name = '⚡ PIC16F84A Assembly Instruction Explorer ⚡';
            
            panelBg = [0.1176 0.1608 0.2314]; % #1E293B
            accentColor = [0.9333 0.6392 0.1882]; % #EEA330 (Glowing Amber)
            textColor = [0.9451 0.9608 0.9765]; % #F1F5F9 (Light Grey)
            buttonBg = [0.1804 0.2235 0.3020];

            % Rebrand App Label Title
            app.ArduinoAppLabel.Text = '⚡ PIC16F84A EXPLORER';
            app.ArduinoAppLabel.FontColor = accentColor;
            app.ArduinoAppLabel.FontName = 'Courier New';
            app.ArduinoAppLabel.Position = [15 565 400 35];

            % Rebrand Connection Panel -> Execution Controller
            app.ConnectionPanel.Title = 'CPU Core Control';
            app.ConnectionPanel.BackgroundColor = panelBg;
            app.ConnectionPanel.ForegroundColor = accentColor;
            app.ConnectButton.Text = '⚙ RUN AUTO DEMO';
            app.ConnectButton.BackgroundColor = buttonBg;
            app.ConnectButton.FontColor = textColor;
            app.ConnectButton.FontWeight = 'bold';
            app.ConnectButton.FontName = 'Courier New';
            app.DisconnectedLampLabel.Text = 'CPU Idle';
            app.DisconnectedLampLabel.FontColor = textColor;
            app.StatusLabel.Text = 'CPU Status:';
            app.StatusLabel.FontColor = textColor;
            app.DisconnectedLamp.Color = [0.5 0.5 0.5]; % Inactive Grey

            % Rebrand Settings Management -> Instruction Assembly
            app.SettingsManagementPanel.Title = 'Instruction Assembly';
            app.SettingsManagementPanel.BackgroundColor = panelBg;
            app.SettingsManagementPanel.ForegroundColor = accentColor;
            app.SaveSettingsButton.Text = '▶ EXECUTE';
            app.SaveSettingsButton.BackgroundColor = [0.0824 0.6275 0.3647]; % Active Green
            app.SaveSettingsButton.FontColor = [1 1 1];
            app.SaveSettingsButton.FontWeight = 'bold';
            app.SaveSettingsButton.FontName = 'Courier New';
            app.LoadSettingsButton.Text = '↺ RESET';
            app.LoadSettingsButton.BackgroundColor = [0.7216 0.2235 0.2235]; % Active Red
            app.LoadSettingsButton.FontColor = [1 1 1];
            app.LoadSettingsButton.FontWeight = 'bold';
            app.LoadSettingsButton.FontName = 'Courier New';
            app.PauseButton.Text = '✨ CHASER LOOP';
            app.PauseButton.BackgroundColor = buttonBg;
            app.PauseButton.FontColor = textColor;
            app.PauseButton.FontWeight = 'bold';
            app.PauseButton.FontName = 'Courier New';

            % Rebrand Data Collection Settings -> Instruction Parameters
            app.DataCollectionSettingsPanel.Title = 'Instruction Parameters';
            app.DataCollectionSettingsPanel.BackgroundColor = panelBg;
            app.DataCollectionSettingsPanel.ForegroundColor = accentColor;
            app.SampleRateSLabel.Text = 'Opcode Selector (1-8):';
            app.SampleRateSLabel.FontColor = textColor;
            app.NumberofSamplesLabel.Text = 'Literal Value (0-255):';
            app.NumberofSamplesLabel.FontColor = textColor;
            
            % Reconfigure Spinners
            app.SampleRateHzSpinner.Limits = [1 8];
            app.SampleRateHzSpinner.Step = 1;
            app.SampleRateHzSpinner.Value = 1;
            app.SampleRateHzSpinner.BackgroundColor = buttonBg;
            app.SampleRateHzSpinner.FontColor = textColor;
            app.SampleRateHzSpinner.FontName = 'Courier New';
            app.SampleRateHzSpinner.FontSize = 16;
            
            app.NumberofSamplesSpinner.Limits = [0 255];
            app.NumberofSamplesSpinner.Step = 1;
            app.NumberofSamplesSpinner.Value = 0;
            app.NumberofSamplesSpinner.BackgroundColor = buttonBg;
            app.NumberofSamplesSpinner.FontColor = textColor;
            app.NumberofSamplesSpinner.FontName = 'Courier New';
            app.NumberofSamplesSpinner.FontSize = 16;

            % Rebrand and Re-initialize Table as Live Register Monitor
            app.SensorTable.ColumnName = {'Register / State', 'Value (HEX)', 'Value (BIN / Detailed State)'};
            app.SensorTable.RowName = {};
            app.SensorTable.BackgroundColor = [panelBg; [0.0706 0.0941 0.1412]];
            app.SensorTable.FontName = 'Courier New';
            app.SensorTable.FontSize = 12;
            app.SensorTable.ColumnEditable = [false, false, false];
            app.SensorTable.Data = table(...
                {'W Register'; 'STATUS Reg'; 'Z Flag'; 'C Flag'; 'PORTB Output'; 'Selected Opcode'}, ...
                {'0x00'; '0x00'; '0x00'; '0x00'; '0x00'; 'Opcode 1'}, ...
                {'00000000'; '00000000'; 'Inactive 🔴'; 'Inactive 🔴'; '00000000'; 'Move Literal to W Register'}, ...
                'VariableNames', {'Register_State', 'Value_HEX', 'Value_BIN_State'});

            % Reconfigure UIAxes for Vector Chip Drawing
            app.UIAxes.Color = [0.0588 0.0902 0.1647]; % Deep dark background
            app.UIAxes.XColor = 'none';
            app.UIAxes.YColor = 'none';
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [0 12];
            grid(app.UIAxes, 'off');
            title(app.UIAxes, 'PIC16F84A 18-Pin DIP Board', 'Color', accentColor, 'FontName', 'Courier New', 'FontSize', 12, 'FontWeight', 'bold');

            % Draw programmatic chip vector shapes
            % Central black body
            rectangle(app.UIAxes, 'Position', [2.5 1 3 10], 'FaceColor', [0.15 0.15 0.15], 'EdgeColor', [0.3 0.3 0.3], 'LineWidth', 2);
            % Notch on top
            rectangle(app.UIAxes, 'Position', [3.5 10.5 1 0.6], 'Curvature', [1 1], 'FaceColor', [0.0588 0.0902 0.1647], 'EdgeColor', [0.3 0.3 0.3]);
            
            % Draw left side pins (Pins 1-9)
            for i = 1:9
                y = 11.2 - i * 1.1;
                rectangle(app.UIAxes, 'Position', [1.2 y 1.3 0.4], 'FaceColor', [0.75 0.75 0.75], 'EdgeColor', [0.6 0.6 0.6]);
                text(app.UIAxes, 0.4, y + 0.25, num2str(i), 'Color', [0.556 0.62 0.72], 'FontName', 'Courier New', 'FontSize', 8, 'FontWeight', 'bold');
            end
            
            % Draw right side pins (Pins 10-18)
            for i = 1:9
                y = 1.3 + (i - 1) * 1.1;
                rectangle(app.UIAxes, 'Position', [5.5 y 1.3 0.4], 'FaceColor', [0.75 0.75 0.75], 'EdgeColor', [0.6 0.6 0.6]);
                text(app.UIAxes, 7.0, y + 0.25, num2str(19 - i), 'Color', [0.556 0.62 0.72], 'FontName', 'Courier New', 'FontSize', 8, 'FontWeight', 'bold');
            end

            % Text markers on chip body
            text(app.UIAxes, 2.7, 9.8, 'RA2', 'Color', [0.5 0.5 0.5], 'FontName', 'Courier New', 'FontSize', 8);
            text(app.UIAxes, 4.5, 9.8, 'RA1', 'Color', [0.5 0.5 0.5], 'FontName', 'Courier New', 'FontSize', 8);
            text(app.UIAxes, 2.7, 8.7, 'RA3', 'Color', [0.5 0.5 0.5], 'FontName', 'Courier New', 'FontSize', 8);
            text(app.UIAxes, 4.5, 8.7, 'RA0', 'Color', [0.5 0.5 0.5], 'FontName', 'Courier New', 'FontSize', 8);
            text(app.UIAxes, 2.7, 7.6, 'RA4', 'Color', [0.5 0.5 0.5], 'FontName', 'Courier New', 'FontSize', 8);
            text(app.UIAxes, 4.5, 7.6, 'OSC1', 'Color', [0.5 0.5 0.5], 'FontName', 'Courier New', 'FontSize', 8);
            
            % Draw 8 Programmatic LED lamps inside the Axes to display PORTB outputs (RB0 to RB7)
            leds = gobjects(1, 8);
            for i = 1:8
                y = 1.3 + (i - 1) * 1.1;
                
                % Draw connection wire line
                line(app.UIAxes, [6.8 8.2], [y+0.2 y+0.2], 'Color', [0.3 0.3 0.3], 'LineWidth', 1.5);
                
                % Draw circle representing LED
                leds(i) = rectangle(app.UIAxes, 'Position', [8.2 y 0.6 0.6], 'Curvature', [1 1], ...
                    'FaceColor', [0.15 0.18 0.25], 'EdgeColor', [0.3 0.3 0.3]);
                
                % Pin Label
                text(app.UIAxes, 9.0, y + 0.3, sprintf('RB%d', i-1), 'Color', accentColor, ...
                    'FontName', 'Courier New', 'FontSize', 8, 'FontWeight', 'bold');
            end

            % State variables initialization
            app.UIFigure.UserData = struct(...
                'WReg', uint8(0), ...
                'StatusReg', uint8(0), ...
                'PortB', uint8(0), ...
                'IsSimulationMode', false, ...
                'TimerMode', 0, ...    % 1 = Chaser, 2 = Demo
                'DemoStep', 0, ...
                'ChaserDir', 1, ...    % 1 = Left, 2 = Right
                'LedHandles', leds ...
            );

            % Polling clock trigger (0.8s interval for fluid visual updating)
            app.PollingTimer = timer("ExecutionMode", "fixedRate", ...
                "Period", 0.8, ...
                "BusyMode", "drop", ...
                "TimerFcn", @app.DoPolling);
                
            updateDisplay(app);
        end

        % Value changed function: ConnectButton (Run Auto Demo)
        function ConnectButtonValueChanged(app, event)
            if app.ConnectButton.Value
                try
                    app.ConnectButton.Text = '⏸ PAUSE DEMO';
                    
                    % Disable chaser button
                    app.PauseButton.Value = false;
                    app.PauseButton.Text = '✨ CHASER LOOP';
                    
                    % Setup Auto Demo mode inside UserData
                    app.UIFigure.UserData.TimerMode = 2;
                    app.UIFigure.UserData.DemoStep = 1;
                    app.UIFigure.UserData.WReg = uint8(0);
                    app.UIFigure.UserData.StatusReg = uint8(0);
                    app.UIFigure.UserData.PortB = uint8(0);
                    updateDisplay(app);
                    
                    app.DisconnectedLamp.Color = [0 0.8 1]; % Cyan lamp indicating Auto Demo is ticking
                    app.DisconnectedLampLabel.Text = 'Demo Active';
                    
                    if strcmp(app.PollingTimer.Running, 'off')
                        start(app.PollingTimer);
                    end
                catch ME
                    app.ConnectButton.Value = false;
                    app.ConnectButton.Text = '⚙ RUN AUTO DEMO';
                    uialert(app.UIFigure, "Demo startup failed: " + ME.message, "Core Mismatch");
                end
            else
                % Stop demo
                stop(app.PollingTimer);
                app.ConnectButton.Text = '⚙ RUN AUTO DEMO';
                app.DisconnectedLamp.Color = [0.5 0.5 0.5];
                app.DisconnectedLampLabel.Text = 'CPU Idle';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.PollingTimer) && isvalid(app.PollingTimer)
                stop(app.PollingTimer);
                delete(app.PollingTimer);
            end 
            app.UIFigure.CloseRequestFcn = '';
            delete(app);
        end

        % Value changed function: SampleRateHzSpinner (Opcode select change callback)
        function SampleRateHzSpinnerValueChanged(app, event)
            updateDisplay(app);
        end

        % Value changed function: NumberofSamplesSpinner (Parameter change callback)
        function NumberofSamplesSpinnerValueChanged(app, event)
            updateDisplay(app);
        end

        % Button pushed function: SaveSettingsButton (Mapped to EXECUTE)
        function SaveSettingsButtonPushed(app, event)
            executeInstruction(app);
            updateDisplay(app);
        end

        % Button pushed function: LoadSettingsButton (Mapped to RESET)
        function LoadSettingsButtonPushed(app, event)
            % Stop active timers
            if isvalid(app.PollingTimer) && strcmp(app.PollingTimer.Running, 'on')
                stop(app.PollingTimer);
            end
            
            % Reset UI buttons
            app.ConnectButton.Value = false;
            app.ConnectButton.Text = '⚙ RUN AUTO DEMO';
            app.PauseButton.Value = false;
            app.PauseButton.Text = '✨ CHASER LOOP';
            
            % Wipe registers
            app.UIFigure.UserData.WReg = uint8(0);
            app.UIFigure.UserData.StatusReg = uint8(0);
            app.UIFigure.UserData.PortB = uint8(0);
            app.UIFigure.UserData.TimerMode = 0;
            app.UIFigure.UserData.DemoStep = 0;
            
            app.DisconnectedLamp.Color = [0.5 0.5 0.5];
            app.DisconnectedLampLabel.Text = 'CPU Idle';
            
            app.SensorTable.Data{6, 3} = '↺ Hardware reset complete. Registers cleared.';
            updateDisplay(app);
        end

        % Value changed function: PauseButton (Mapped to CHASER LOOP)
        function PauseButtonValueChanged(app, event)
            if app.PauseButton.Value
                try
                    app.PauseButton.Text = '⏸ PAUSE LOOP';
                    
                    % Disable demo button
                    app.ConnectButton.Value = false;
                    app.ConnectButton.Text = '⚙ RUN AUTO DEMO';
                    
                    % Setup Chaser loop mode inside UserData
                    app.UIFigure.UserData.TimerMode = 1;
                    app.UIFigure.UserData.PortB = uint8(1); % RB0 active
                    app.UIFigure.UserData.ChaserDir = 1;
                    updateDisplay(app);
                    
                    app.DisconnectedLamp.Color = [0.9333 0.6392 0.1882]; % Amber lamp indicating active pin loop
                    app.DisconnectedLampLabel.Text = 'Chaser Active';
                    
                    if strcmp(app.PollingTimer.Running, 'off')
                        start(app.PollingTimer);
                    end
                catch ME
                    app.PauseButton.Value = false;
                    app.PauseButton.Text = '✨ CHASER LOOP';
                    uialert(app.UIFigure, "Chaser loop failed: " + ME.message, "State Error");
                end
            else
                % Stop chaser
                stop(app.PollingTimer);
                app.PauseButton.Text = '✨ CHASER LOOP';
                app.DisconnectedLamp.Color = [0.5 0.5 0.5];
                app.DisconnectedLampLabel.Text = 'CPU Idle';
            end
        end

        % Cell edit callback: SensorTable
        function SensorTableCellEdit(app, event)
            % Edits are locked programmatically inside startupFcn
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Arduino Pin Reading')
            xlabel(app.UIAxes, 'Samples')
            ylabel(app.UIAxes, 'Measurement')
            zlabel(app.UIAxes, 'Z')
            app.ArduinoAppLabel = uilabel(app.UIFigure);
            app.ArduinoAppLabel.Text = 'Arduino App';
            app.SensorTable = uitable(app.UIFigure);
            app.SensorTable.ColumnName = {'Pin'; 'Name'; 'Unit'; 'Scale'; 'Offset'; 'Enable'};
            app.SensorTable.RowName = {};
            app.SensorTable.ColumnEditable = true;
            app.SensorTable.CellEditCallback = createCallbackFcn(app, @SensorTableCellEdit, true);
            app.ConnectionPanel = uipanel(app.UIFigure);
            app.ConnectionPanel.Title = 'Connection';
            app.DisconnectedLampLabel = uilabel(app.ConnectionPanel);
            app.DisconnectedLampLabel.Text = 'Disconnected';
            app.DisconnectedLamp = uilamp(app.ConnectionPanel);
            app.ConnectButton = uibutton(app.ConnectionPanel, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.StatusLabel = uilabel(app.ConnectionPanel);
            app.StatusLabel.Text = 'Status:';
            app.SettingsManagementPanel = uipanel(app.UIFigure);
            app.SettingsManagementPanel.Title = 'Settings Management';
            app.PauseButton = uibutton(app.SettingsManagementPanel, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Text = 'Pause Plot';
            app.LoadSettingsButton = uibutton(app.SettingsManagementPanel, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Text = 'Load Settings';
            app.SaveSettingsButton = uibutton(app.SettingsManagementPanel, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButtonPushed, true);
            app.SaveSettingsButton.Text = 'Save Settings';
            app.DataCollectionSettingsPanel = uipanel(app.UIFigure);
            app.DataCollectionSettingsPanel.Title = 'Data Collection Settings';
            app.SampleRateSLabel = uilabel(app.DataCollectionSettingsPanel);
            app.SampleRateSLabel.Text = 'Sample Rate (Hz):';
            app.SampleRateHzSpinner = uispinner(app.DataCollectionSettingsPanel);
            app.SampleRateHzSpinner.Step = 0.01;
            app.SampleRateHzSpinner.Limits = [0.1 2.5];
            app.SampleRateHzSpinner.ValueChangedFcn = createCallbackFcn(app, @SampleRateHzSpinnerValueChanged, true);
            app.SampleRateHzSpinner.Value = 1;
            app.NumberofSamplesLabel = uilabel(app.DataCollectionSettingsPanel);
            app.NumberofSamplesLabel.Text = 'Number of Samples:';
            app.NumberofSamplesSpinner = uispinner(app.DataCollectionSettingsPanel);
            app.NumberofSamplesSpinner.Step = 10;
            app.NumberofSamplesSpinner.Limits = [10 1000];
            app.NumberofSamplesSpinner.RoundFractionalValues = 'on';
            app.NumberofSamplesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofSamplesSpinnerValueChanged, true);
            app.NumberofSamplesSpinner.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HarryRogers_22835293_EO631_GUI_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end